classdef CVaRopt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIMainFigure                    matlab.ui.Figure
        CVaRportfoliooptimizationLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        DefineTab                       matlab.ui.container.Tab
        Lamp                            matlab.ui.control.Lamp
        OptimizeButton                  matlab.ui.control.Button
        NumberofportfoliosEditField     matlab.ui.control.NumericEditField
        NumberofportfoliosEditFieldLabel  matlab.ui.control.Label
        ProbabilitylevelEditField       matlab.ui.control.NumericEditField
        ProbabilitylevelEditFieldLabel  matlab.ui.control.Label
        SimulateassetscenariosPanel     matlab.ui.container.Panel
        SimulationmethodDropDown        matlab.ui.control.DropDown
        SimulationmethodDropDownLabel   matlab.ui.control.Label
        NumberofscenariosEditField      matlab.ui.control.NumericEditField
        NumberofscenariosEditFieldLabel  matlab.ui.control.Label
        NumberofselectedassetsEditField  matlab.ui.control.NumericEditField
        NumberofselectedassetsEditFieldLabel  matlab.ui.control.Label
        SelectAssetsListBox             matlab.ui.control.ListBox
        SelectassetsLabel               matlab.ui.control.Label
        PlotTab                         matlab.ui.container.Tab
        PortNumEditField                matlab.ui.control.NumericEditField
        PortfolionumberSlider           matlab.ui.control.Slider
        PortfolionumberSliderLabel      matlab.ui.control.Label
        PlotTypeDropDown                matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        lampOffColor = [0.8 0.8 0.8];
        lampReadyColor = [0 1 0];  % green
        assetsTable = table();
        frontWgts = [];
        frontRisk = [];
        frontReturn = [];
        frontStd = [];
        frontVaR = [];
        PortfolioAssetNames = {};
        PortfolioAssetReturns = [];
        AssetScenarios = [];
    end

    methods (Access = public)

        function plotWeight(app)
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes.XGrid = 'off';
            app.UIAxes.YGrid = 'off';
            w = app.frontWgts'*100;
            assetsKeep = ~all(w < 0.01);
            w = w(:,assetsKeep);
            nPort = size(w,1);
            nAssets = size(w,2);
            ylabel(app.UIAxes,'Portfolio weight (%)')
            xlabel(app.UIAxes,'Portfolio number')
            title(app.UIAxes,"");
            ylim(app.UIAxes,[0 100]);
            xlim(app.UIAxes,[0,nPort+1])
            assetNamesKeep = app.PortfolioAssetNames(assetsKeep);
            b = bar(app.UIAxes,1:nPort, w,'stacked');
            cmap = jet(nAssets);
            for i=1:nAssets
                b(i).FaceColor = cmap(i,:);
                %b(i).EdgeColor = 'w';
            end
            
            legend(app.UIAxes,assetNamesKeep,'NumColumns',ceil(nAssets/6));
        end
        
        function plotFrontier(app)
            app.UIAxes.Toolbar.Visible = 'off';
            if ~isempty(app.UIAxes.Legend)
                app.UIAxes.Legend.Visible = 'off';
            end
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            plot(app.UIAxes, 100*app.frontRisk,100*app.frontReturn, 'Color','r','LineStyle',"-",'Marker',"o",'LineWidth',1.5)
            title(app.UIAxes, 'Efficient frontier')
            xlabel(app.UIAxes,'CVaR of portfolio (%)')
            ylabel(app.UIAxes,'Mean of portfolio returns (%)')
            % set x limit and y limit
            minRet = min(100*app.frontReturn);
            maxRet = max(100*app.frontReturn);
            margin = 0.04;
            ylim(app.UIAxes,[minRet-margin*(maxRet-minRet),maxRet+margin*(maxRet-minRet)])
            minRisk = min(100*app.frontRisk);
            maxRisk = max(100*app.frontRisk);
            xlim(app.UIAxes,[minRisk-margin*(maxRisk-minRisk),maxRisk+margin*(maxRisk-minRisk)])
        end
        
        function plotPortfolioHistogram(app)
            portNum = app.PortfolionumberSlider.Value;
            VaR = -100*app.frontVaR(portNum);
            CVaR = -100*app.frontRisk(portNum);
            portRet = 100*app.PortfolioAssetReturns * app.frontWgts(:,portNum);
            nBin = 40;
            app.UIAxes.Toolbar.Visible = 'off';
            if ~isempty(app.UIAxes.Legend)
                app.UIAxes.Legend.Visible = 'off';
            end
            app.UIAxes.XGrid = 'off';
            app.UIAxes.YGrid = 'off';
            h1 = histogram(app.UIAxes, portRet,nBin);
            hold(app.UIAxes,'on')
            title(app.UIAxes, 'Histogram of returns');
            xlabel(app.UIAxes, 'Returns (%)')
            ylabel(app.UIAxes, 'Frequency')
            % Highlight bins with lower edges < VaR level in red
            edges = h1.BinEdges;
            app.UIAxes.XLim = [min(edges)-0.05*(max(edges)-min(edges)), max(edges)+0.05*(max(edges)-min(edges))];
            app.UIAxes.YLim = [0 max(h1.Values)+2];
            counts = h1.Values.*(edges(1:end-1) < VaR);
            %
            h2 = histogram(app.UIAxes, 'BinEdges',edges,'BinCounts',counts);
            h2.FaceColor = 'r';
            % Add CVaR line
            plot(app.UIAxes, [CVaR;CVaR],[0;max(h1.BinCounts)*0.80],'--r')
            % Add CVaR text
            text(app.UIAxes, edges(3), max(h1.BinCounts)*0.85,['CVaR = ' num2str(round(-CVaR,3)) '%'])
            hold(app.UIAxes,'off')
        end

    end

    methods(Static)
        function AScenarios = simEmpirical(ret, nScenario)
            % Simulate empirical scenarios given historical returns
            [nSample,nAsset] = size(ret);
            u = zeros(nSample,nAsset);
            % Estimate the cumulative distribution function for each
            % variable
            for i = 1:nAsset
                u(:,i) = ksdensity(ret(:,i),ret(:,i),'Function','cdf');
            end
            % Fit a t copula to data
            [rho, dof] = copulafit('t',u);
            % Generate copula random numbers
            r = copularnd('t',rho,dof,nScenario);
            AScenarios = zeros(nScenario,nAsset);
            for i = 1:nAsset
                AScenarios(:,i) = ksdensity(ret(:,i),r(:,i),'function','icdf');
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %  Load a Matlab default datatable
            T = readtable('dowPortfolio.xlsx');
            app.assetsTable = T;
            % Populate asset's list box
            app.SelectAssetsListBox.Items = T.Properties.VariableNames(2:end);
            app.Lamp.Color = app.lampOffColor;
        end

        % Value changed function: SelectAssetsListBox
        function SelectAssetsListBoxValueChanged(app, event)
            value = app.SelectAssetsListBox.Value;
            app.NumberofselectedassetsEditField.Value = numel(value);
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            % Optimize only if at least 2 assets have been selected
            if app.NumberofselectedassetsEditField.Value < 2
                return
            end
            app.Lamp.Color = app.lampOffColor;
            app.UIMainFigure.Pointer = 'watch';
            drawnow;
            % Get returns of selected assets
            symbol = app.SelectAssetsListBox.Value;
            ret = tick2ret(app.assetsTable{:,symbol});
            app.PortfolioAssetReturns = ret;
            % Simulate return scenarios
            simulationMethod = app.SimulationmethodDropDown.Value;
            nScenario = app.NumberofscenariosEditField.Value;
            switch simulationMethod
                case 'Normal'% Based on normal distribution
                    AScenarios = mvnrnd(mean(ret),cov(ret),nScenario);
                case 'Empirical' % Based on empirical distribution using t-copula
                    AScenarios = app.simEmpirical(ret,nScenario);
            end
            app.AssetScenarios = AScenarios;
            % Define CVaR portfolio
            p1 = PortfolioCVaR('Scenarios', AScenarios);
            p1 = setAssetList(p1,symbol);
            p1 = setDefaultConstraints(p1);
            alpha = app.ProbabilitylevelEditField.Value;
            p1 = setProbabilityLevel(p1, alpha);
            nPort = app.NumberofportfoliosEditField.Value;
            % Estimate Frontier, calculate and store risk/return
            app.frontWgts = estimateFrontier(p1,nPort);
            app.frontRisk = estimatePortRisk(p1,app.frontWgts);
            app.frontReturn = estimatePortReturn(p1,app.frontWgts);
            app.frontStd = estimatePortStd(p1,app.frontWgts);
            app.frontVaR = estimatePortVaR(p1,app.frontWgts);
            app.PortfolioAssetNames = symbol;

            app.Lamp.Color = app.lampReadyColor;
            app.UIMainFigure.Pointer = 'arrow';
            drawnow;
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
            value = app.PlotTypeDropDown.Value;
            switch value
                case 'Portfolio weights'
                    app.PortfolionumberSlider.Enable = 'off';
                    app.plotWeight()
                case 'Efficient frontier'
                    app.PortfolionumberSlider.Enable = 'off';
                    app.plotFrontier()
                case 'Histogram of returns'
                    app.PortfolionumberSlider.Enable = 'on';
                    app.plotPortfolioHistogram()
            end
        end

        % Callback function
        function DropDownOpening(app, event)

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title, 'Plot')
                if isempty(app.frontWgts)
                    app.UIAxes.Visible = 'off';
                    app.PlotTypeDropDown.Enable = 'off';
                    app.PortfolionumberSlider.Enable = 'off';
                else
                    app.UIAxes.Visible = 'on';
                    app.PlotTypeDropDown.Enable = 'on';
                    app.PortfolionumberSlider.Enable = 'on';
                    app.PortfolionumberSlider.Limits = [1,length(app.frontReturn)];
                    app.PlotTypeDropDownValueChanged([])
                end
            end
        end

        % Value changed function: PortfolionumberSlider
        function PortfolionumberSliderValueChanged(app, event)
            value = app.PortfolionumberSlider.Value;
            app.PortfolionumberSlider.Value = round(value);
            app.PortNumEditField.Value = round(value);
            app.PlotTypeDropDownValueChanged([])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIMainFigure = uifigure('Visible', 'off');
            app.UIMainFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIMainFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DefineTab = uitab(app.TabGroup);
            app.DefineTab.Title = 'Define';
            app.SelectassetsLabel = uilabel(app.DefineTab);
            app.SelectassetsLabel.Text = 'Select assets';
            app.SelectAssetsListBox = uilistbox(app.DefineTab);
            app.SelectAssetsListBox.Items = {};
            app.SelectAssetsListBox.Multiselect = 'on';
            app.SelectAssetsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAssetsListBoxValueChanged, true);
            app.SelectAssetsListBox.Value = {};
            app.NumberofselectedassetsEditFieldLabel = uilabel(app.DefineTab);
            app.NumberofselectedassetsEditFieldLabel.Text = 'Number of selected assets';
            app.NumberofselectedassetsEditField = uieditfield(app.DefineTab, 'numeric');
            app.NumberofselectedassetsEditField.Limits = [0 Inf];
            app.NumberofselectedassetsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofselectedassetsEditField.Editable = 'off';
            app.SimulateassetscenariosPanel = uipanel(app.DefineTab);
            app.SimulateassetscenariosPanel.Title = 'Simulate asset scenarios';
            app.NumberofscenariosEditFieldLabel = uilabel(app.SimulateassetscenariosPanel);
            app.NumberofscenariosEditFieldLabel.Text = 'Number of scenarios';
            app.NumberofscenariosEditField = uieditfield(app.SimulateassetscenariosPanel, 'numeric');
            app.NumberofscenariosEditField.Limits = [1000 1000000];
            app.NumberofscenariosEditField.ValueDisplayFormat = '%.0f';
            app.NumberofscenariosEditField.Value = 5000;
            app.SimulationmethodDropDownLabel = uilabel(app.SimulateassetscenariosPanel);
            app.SimulationmethodDropDownLabel.Text = 'Simulation method';
            app.SimulationmethodDropDown = uidropdown(app.SimulateassetscenariosPanel);
            app.SimulationmethodDropDown.Items = {'Normal', 'Empirical'};
            app.SimulationmethodDropDown.Value = 'Normal';
            app.ProbabilitylevelEditFieldLabel = uilabel(app.DefineTab);
            app.ProbabilitylevelEditFieldLabel.Text = 'Probability level';
            app.ProbabilitylevelEditField = uieditfield(app.DefineTab, 'numeric');
            app.ProbabilitylevelEditField.Limits = [0.51 0.999];
            app.ProbabilitylevelEditField.Value = 0.95;
            app.NumberofportfoliosEditFieldLabel = uilabel(app.DefineTab);
            app.NumberofportfoliosEditFieldLabel.Text = 'Number of portfolios';
            app.NumberofportfoliosEditField = uieditfield(app.DefineTab, 'numeric');
            app.NumberofportfoliosEditField.Limits = [10 1000];
            app.NumberofportfoliosEditField.Value = 40;
            app.OptimizeButton = uibutton(app.DefineTab, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.Lamp = uilamp(app.DefineTab);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            app.PlotTypeDropDown = uidropdown(app.PlotTab);
            app.PlotTypeDropDown.Items = {'Efficient frontier', 'Portfolio weights', 'Histogram of returns'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Efficient frontier';
            app.PortfolionumberSliderLabel = uilabel(app.PlotTab);
            app.PortfolionumberSliderLabel.Text = 'Portfolio number';
            app.PortfolionumberSlider = uislider(app.PlotTab);
            app.PortfolionumberSlider.Limits = [1 40];
            app.PortfolionumberSlider.MajorTicks = [1 10 20 30 40];
            app.PortfolionumberSlider.ValueChangedFcn = createCallbackFcn(app, @PortfolionumberSliderValueChanged, true);
            app.PortfolionumberSlider.Value = 1;
            app.PortNumEditField = uieditfield(app.PlotTab, 'numeric');
            app.PortNumEditField.Limits = [1 Inf];
            app.PortNumEditField.ValueDisplayFormat = '%.0f';
            app.PortNumEditField.Editable = 'off';
            app.PortNumEditField.Value = 1;
            app.CVaRportfoliooptimizationLabel = uilabel(app.UIMainFigure);
            app.CVaRportfoliooptimizationLabel.Text = 'CVaR portfolio optimization';
            % Show the figure after all components are created
            app.UIMainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVaRopt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIMainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIMainFigure)
        end
    end
end